classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        Soal1Tab                   matlab.ui.container.Tab
        InputMaskTextAreaLabel     matlab.ui.control.Label
        srcEditField               matlab.ui.control.EditField
        PilihGambarButton          matlab.ui.control.Button
        InputMaskTextArea          matlab.ui.control.TextArea
        MaskinvalidLabel           matlab.ui.control.Label
        srcEditFieldLabel          matlab.ui.control.Label
        UIAxes_Input               matlab.ui.control.UIAxes
        UIAxes_Output              matlab.ui.control.UIAxes
        UIAxes_Matlab              matlab.ui.control.UIAxes
        Soal2Tab                   matlab.ui.container.Tab
        nEditField2_2Label         matlab.ui.control.Label
        nEditField2Label           matlab.ui.control.Label
        srcEditField2              matlab.ui.control.EditField
        PilihGambarButton2         matlab.ui.control.Button
        OperationDropDown2         matlab.ui.control.DropDown
        nEditField2                matlab.ui.control.NumericEditField
        sigmaEditField2            matlab.ui.control.NumericEditField
        D0EditField2               matlab.ui.control.NumericEditField
        nEditField2_2              matlab.ui.control.NumericEditField
        sigmaEditField2Label       matlab.ui.control.Label
        D0EditField2Label          matlab.ui.control.Label
        OperationDropDown2Label    matlab.ui.control.Label
        srcEditField2_Label        matlab.ui.control.Label
        UIAxes2_Input              matlab.ui.control.UIAxes
        UIAxes2_Output             matlab.ui.control.UIAxes
        Soal3Tab                   matlab.ui.container.Tab
        srcEditField3              matlab.ui.control.EditField
        PilihGambarButton3         matlab.ui.control.Button
        OperationDropDown3         matlab.ui.control.DropDown
        D0EditField3               matlab.ui.control.NumericEditField
        D0EditField3Label          matlab.ui.control.Label
        nEditField3                matlab.ui.control.NumericEditField
        nEditField3Label           matlab.ui.control.Label
        OperationDropDown3Label    matlab.ui.control.Label
        srcEditField3Label         matlab.ui.control.Label
        UIAxes3_Input              matlab.ui.control.UIAxes
        UIAxes3_Output             matlab.ui.control.UIAxes
        Soal4Tab                   matlab.ui.container.Tab
        srcEditField4              matlab.ui.control.EditField
        PilihGambarButton4         matlab.ui.control.Button
        D0EditField4               matlab.ui.control.NumericEditField
        alphaEditField4            matlab.ui.control.NumericEditField
        nEditField3Label_2         matlab.ui.control.Label
        D0EditField3Label_2        matlab.ui.control.Label
        srcEditField3Label_2       matlab.ui.control.Label
        UIAxes4_Input              matlab.ui.control.UIAxes
        UIAxes4_Output             matlab.ui.control.UIAxes
        Soal5Tab                   matlab.ui.container.Tab
        nEditField2Label_2         matlab.ui.control.Label
        srcEditField5              matlab.ui.control.EditField
        PilihGambarButton5         matlab.ui.control.Button
        NoiseDropDown5             matlab.ui.control.DropDown
        dEditField5                matlab.ui.control.NumericEditField
        dEditField5Label           matlab.ui.control.Label
        meanEditField5             matlab.ui.control.NumericEditField
        meanEditField5Label        matlab.ui.control.Label
        varianceEditField5         matlab.ui.control.NumericEditField
        varianceEditField5Label    matlab.ui.control.Label
        FilterDropDown5            matlab.ui.control.DropDown
        nEditField5_2              matlab.ui.control.NumericEditField
        QEditField5                matlab.ui.control.NumericEditField
        QEditField5Label           matlab.ui.control.Label
        alphaEditField5            matlab.ui.control.NumericEditField
        alphaEditField5Label       matlab.ui.control.Label
        OperationDropDown3Label_3  matlab.ui.control.Label
        OperationDropDown3Label_2  matlab.ui.control.Label
        srcEditField_2Label        matlab.ui.control.Label
        UIAxes5_Input              matlab.ui.control.UIAxes
        UIAxes5_Noisy              matlab.ui.control.UIAxes
        UIAxes5_Filtered           matlab.ui.control.UIAxes
        Soal6Tab                   matlab.ui.container.Tab
        GambarDropDown             matlab.ui.control.DropDown
        GambarDropDownLabel        matlab.ui.control.Label
        UIAxes6_Output             matlab.ui.control.UIAxes
        UIAxes6_Input              matlab.ui.control.UIAxes
        Soal7Tab                   matlab.ui.container.Tab
        PanelUiAxes7               matlab.ui.container.Panel
        UIAxes7_Blur               matlab.ui.control.UIAxes
        KEditField                 matlab.ui.control.NumericEditField
        KEditFieldLabel            matlab.ui.control.Label
        ApplyButton                matlab.ui.control.Button
        D0EditField7               matlab.ui.control.NumericEditField
        FilterSizeLabel            matlab.ui.control.Label
        ThetadegreeEditField       matlab.ui.control.NumericEditField
        ThetadegreeEditFieldLabel  matlab.ui.control.Label
        LengthEditField            matlab.ui.control.NumericEditField
        LengthEditFieldLabel       matlab.ui.control.Label
        TypeDropDown               matlab.ui.control.DropDown
        OperationDropDown3Label_4  matlab.ui.control.Label
        srcEditField7              matlab.ui.control.EditField
        srcEditField_2Label_2      matlab.ui.control.Label
        PilihGambarButton7         matlab.ui.control.Button
        UIAxes7_Input              matlab.ui.control.UIAxes
        UIAxes7_Output             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        sec7in % Input of section 7
    end
    
    methods (Access = private)
        function [img, fullPath] = browseImage(app)
           [file, path] = uigetfile('*.png;*.jpg;*.bmp;*.tif', 'Pilih Gambar');

            
            if isequal(file, 0)  % User clicked cancel
                img = [];        % Return empty image
                fullPath = '';   % Return empty path
            else
                fullPath = fullfile(path, file);  % Concatenate path and file
                [img, map] = imread(fullPath);
                if ~isempty(map)
                    % Convert the indexed image to RGB
                    img = ind2rgb(img, map);
                end
            end

            drawnow;
            figure(app.UIFigure)
        end
        
        function section1_apply_convolution(app)
            if app.srcEditField.Value == ""
                cla(app.UIAxes_Output, 'reset');
                cla(app.UIAxes_Matlab, 'reset');
            else
                [mask, maskValid] = parse_mask_input(app.InputMaskTextArea.Value);
                if ~maskValid
                    app.MaskinvalidLabel.Visible = 'on';
                    return;
                end
                app.MaskinvalidLabel.Visible = 'off';
                img = imread(app.srcEditField.Value);
                conv_img = image_convolution(img, mask);

                mask_sum = sum(mask(:));
                matlab_conv_img = imfilter(im2double(img), mask, 'replicate', 'conv');
                if mask_sum ~= 0
                    matlab_conv_img = matlab_conv_img / mask_sum;
                end
                matlab_conv_img = im2uint8(matlab_conv_img);

                imshow(conv_img, 'Parent', app.UIAxes_Output);
                imshow(matlab_conv_img, 'Parent', app.UIAxes_Matlab);
            end
        end

        function transformed_img = section2_get_transformation(app, img)
            operation_value = app.OperationDropDown2.Value;
            switch operation_value
                case 'Mean Filter'
                    n_value = app.nEditField2.Value;
    
                    transformed_img = mean_filter(img, n_value);
                case 'Gaussian Filter'
                    n_value = app.nEditField2.Value;
                    sigma_value = app.sigmaEditField2.Value;
    
                    transformed_img = gaussian_filter(img, n_value, sigma_value);
                case 'ILPF'
                    D0_value = app.D0EditField2.Value;
    
                    transformed_img = ILPF(img, D0_value);
                case 'GLPF'
                    D0_value = app.D0EditField2.Value;
    
                    transformed_img = GLPF(img, D0_value);
                case 'BLPF'
                    D0_value = app.D0EditField2.Value;
                    n_value = app.nEditField2_2.Value;
    
                    transformed_img = BLPF(img, D0_value, n_value);
            end
        end
    
        function section2_apply_transformation(app)
            if app.srcEditField2.Value == ""
                cla(app.UIAxes2_Output, 'reset');
            else
                img = imread(app.srcEditField2.Value);
                img = section2_get_transformation(app, img);
                imshow(img, 'Parent', app.UIAxes2_Output);
            end
        end

        function transformed_img = section3_get_transformation(app, img)
            operation_value = app.OperationDropDown3.Value;
            switch operation_value
                case 'IHPF'
                    D0_value = app.D0EditField3.Value;
    
                    transformed_img = IHPF(img, D0_value);
                case 'GHPF'
                    D0_value = app.D0EditField3.Value;
    
                    transformed_img = GHPF(img, D0_value);
                case 'BHPF'
                    D0_value = app.D0EditField3.Value;
                    n_value = app.nEditField3.Value;
    
                    transformed_img = BHPF(img, D0_value, n_value);
            end
        end
    
        function section3_apply_transformation(app)
            if app.srcEditField3.Value == ""
                cla(app.UIAxes3_Output, 'reset');
            else
                img = imread(app.srcEditField3.Value);
                img = section3_get_transformation(app, img);
                imshow(img, 'Parent', app.UIAxes3_Output);
            end
        end

        function noisy_img = section5_get_noisy(app, img)
            noise_value = app.NoiseDropDown5.Value;
            switch noise_value
                case 'Salt and Pepper Noise'
                    n_value = app.dEditField5.Value;

                    noisy_img = salt_and_pepper_noise(img, n_value);
                case 'Gaussian Noise'
                    mean_value = app.meanEditField5.Value;
                    variance_value = app.varianceEditField5.Value;

                    noisy_img = gaussian_noise(img, mean_value, variance_value);
            end
        end

        function filtered_img = section5_get_filtered(app, img)
            filter_value = app.FilterDropDown5.Value;
            n_value = app.nEditField5_2.Value;
            switch filter_value
                case 'Min Filter'
                    filtered_img = min_filter(img, n_value);
                case 'Max Filter'
                    filtered_img = max_filter(img, n_value);
                case 'Median Filter'
                    filtered_img = median_filter(img, n_value);
                case 'Arithmetic Mean Filter'
                    filtered_img = arithmetic_mean_filter(img, n_value);
                case 'Geometric Filter'
                    filtered_img = geometric_filter(img, n_value);
                case 'Harmonic Mean Filter'
                    filtered_img = harmonic_mean_filter(img, n_value);
                case 'Contraharmonic Mean Filter'
                    Q_value = app.QEditField5.Value;
                    filtered_img = contraharmonic_mean_filter(img, n_value, Q_value);
                case 'Midpoint Filter'
                    filtered_img = midpoint_filter(img, n_value);
                case 'Alpha-Trimmed Mean Filter'
                    alpha_value = app.alphaEditField5.Value;
                    filtered_img = alpha_trimmed_mean_filter(img, n_value, alpha_value);
            end
        end

        function section5_apply_noise_and_filter(app)
            if app.srcEditField5.Value == ""
                cla(app.UIAxes5_Noisy, 'reset');
                cla(app.UIAxes5_Filtered, 'reset');
            else
                img = imread(app.srcEditField5.Value);
                noisy_img = section5_get_noisy(app, img);
                filtered_img = section5_get_filtered(app, noisy_img);
                imshow(noisy_img, 'Parent', app.UIAxes5_Noisy);
                imshow(filtered_img, 'Parent', app.UIAxes5_Filtered);
            end
        end

        function section6_update(app, isShow)
            switch app.GambarDropDown.Value
                case 'a'
                    in = imread('img/6a.jpg');
                    out = butterworth_notch_filter(in, [105 105], 10, 2, isShow);
                case 'b'
                    in = imread('img/6b.jpg');
                    out = butterworth_notch_filter(in, [106 58], 10, 1,isShow);
                case 'c'
                    in = imread('img/6c.bmp');
                    out = ideal_notch_filter(in, [241 256], 1, isShow);
                case 'd'
                    in = imread('img/6d.jpg');
                    out = line_notch_filter(in, [232], [], 2, 1, isShow);
                    out = ideal_notch_filter(in, [331 197;331 222;331 273;331 299], 1, isShow);
                case 'e'
                    in = imread('img/6e.tif');
                    out = line_notch_filter(in,[14; 41; 61; 67; 88; 165; 138; 111; 192; 212; 219; 238; 244; 263; 290], [13; 60; 108; 116; 134; 165; 184; 191], 5, 1, isShow);
                case 'f'
                    [in, map] = imread('img/6f.png');
                    in = ind2rgb(in, map);
                    out = butterworth_notch_filter(in, [260 40;161 107;62 173;162 241], 20, 2, isShow);
            end
            imshow(in, 'Parent', app.UIAxes6_Input);
            imshow(out, 'Parent', app.UIAxes6_Output);
        end
        function section7_apply(app)
            img = app.sec7in;
            switch app.TypeDropDown.ValueIndex
                case 1
                    app.PanelUiAxes7.Visible = true;
                    h = fspecial('motion', app.LengthEditField.Value, app.ThetadegreeEditField.Value);
                    blurred = imfilter(img, h, 'circular');
                    imshow(blurred, 'Parent', app.UIAxes7_Blur);
                    deconvOut = wiener_filter(blurred, h, app.KEditField.Value);
                    imshow(deconvOut, 'Parent', app.UIAxes7_Output);
                case 2
                    app.PanelUiAxes7.Visible = false;
                    deconvOut = wiener_filter_GLPF(img, app.D0EditField7.Value, app.KEditField.Value);
                    imshow(deconvOut, 'Parent', app.UIAxes7_Output);
            end
        end
        
        function section4_apply_filter(app)
            if app.srcEditField4.Value == ""
                cla(app.UIAxes4_Output, 'reset');
            else
                img = imread(app.srcEditField4.Value);
                D0_value = app.D0EditField4.Value;
                alpha_value = app.alphaEditField4.Value;
                img = high_boost_filter(img, D0_value, alpha_value);
                imshow(img, 'Parent', app.UIAxes4_Output);
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            section6_update(app, false);
        end

        % Button pushed function: PilihGambarButton
        function PilihGambarButtonPushed(app, event)
            [img, fullPath] = browseImage(app);
            app.srcEditField.Value = fullPath;
            imshow(img, 'Parent', app.UIAxes_Input);
            section1_apply_convolution(app);
        end

        % Value changed function: InputMaskTextArea
        function InputMaskTextAreaValueChanged(app, event)
            section1_apply_convolution(app);
        end

        % Button pushed function: PilihGambarButton2
        function PilihGambarButton2Pushed(app, event)
            [img, fullPath] = browseImage(app);
            app.srcEditField2.Value = fullPath;
            imshow(img, 'Parent', app.UIAxes2_Input);
            section2_apply_transformation(app);
        end

        % Value changed function: OperationDropDown2
        function OperationDropDown2ValueChanged(app, event)
            value = app.OperationDropDown2.Value;
                
            app.nEditField2.Visible = 'off';
            app.nEditField2_2Label.Visible = 'off';
            app.sigmaEditField2.Visible = 'off';
            app.sigmaEditField2Label.Visible = 'off';
            app.D0EditField2.Visible = 'off';
            app.D0EditField2Label.Visible = 'off';
            app.nEditField2_2.Visible = 'off';
            app.nEditField2Label.Visible = 'off';
            
            switch value
                case 'Mean Filter'
                    app.nEditField2.Visible = 'on';
                    app.nEditField2_2Label.Visible = 'on';
            
                    app.nEditField2.Value = 3;
                case 'Gaussian Filter'
                    app.nEditField2.Visible = 'on';
                    app.nEditField2_2Label.Visible = 'on';
                    app.sigmaEditField2.Visible = 'on';
                    app.sigmaEditField2Label.Visible = 'on';
            
                    app.nEditField2.Value = 3;
                    app.sigmaEditField2.Value = 3;
                case 'ILPF'
                    app.D0EditField2.Visible = 'on';
                    app.D0EditField2Label.Visible = 'on';
            
                    app.D0EditField2.Value = 50;
                case 'GLPF'
                    app.D0EditField2.Visible = 'on';
                    app.D0EditField2Label.Visible = 'on';
            
                    app.D0EditField2.Value = 50;
                case 'BLPF'
                    app.D0EditField2.Visible = 'on';
                    app.D0EditField2Label.Visible = 'on';
                    app.nEditField2_2.Visible = 'on';
                    app.nEditField2_2Label.Visible = 'on';
            
                    app.D0EditField2.Value = 50;
                    app.nEditField2_2.Value = 1;
            end
    
            section2_apply_transformation(app);
        end

        % Value changed function: D0EditField2, nEditField2, 
        % ...and 2 other components
        function Section2EditFieldValueChanged(app, event)
            section2_apply_transformation(app);
        end

        % Button pushed function: PilihGambarButton3
        function PilihGambarButton3Pushed(app, event)
            [img, fullPath] = browseImage(app);
            app.srcEditField3.Value = fullPath;
            imshow(img, 'Parent', app.UIAxes3_Input);
            section3_apply_transformation(app);
        end

        % Value changed function: OperationDropDown3
        function OperationDropDown3ValueChanged(app, event)
            value = app.OperationDropDown3.Value;
                
            app.D0EditField3.Visible = 'off';
            app.D0EditField3Label.Visible = 'off';
            app.nEditField3.Visible = 'off';
            app.nEditField3Label.Visible = 'off';
            
            switch value
                case 'IHPF'
                    app.D0EditField3.Visible = 'on';
                    app.D0EditField3Label.Visible = 'on';
            
                    app.D0EditField3.Value = 50;
                case 'GHPF'
                    app.D0EditField3.Visible = 'on';
                    app.D0EditField3Label.Visible = 'on';
            
                    app.D0EditField3.Value = 50;
                case 'BHPF'
                    app.D0EditField3.Visible = 'on';
                    app.D0EditField3Label.Visible = 'on';
                    app.nEditField3.Visible = 'on';
                    app.nEditField3Label.Visible = 'on';
            
                    app.D0EditField3.Value = 50;
                    app.nEditField3.Value = 1;
            end
    
            section3_apply_transformation(app);
        end

        % Value changed function: D0EditField3
        function Section3EditFieldValueChanged(app, event)
            section3_apply_transformation(app);
        end

        % Button pushed function: PilihGambarButton5
        function PilihGambarButton5Pushed(app, event)
            [img, fullPath] = browseImage(app);
            app.srcEditField5.Value = fullPath;
            imshow(img, 'Parent', app.UIAxes5_Input);
            section5_apply_noise_and_filter(app);
        end

        % Value changed function: NoiseDropDown5
        function NoiseDropDown5ValueChanged(app, event)
            value = app.NoiseDropDown5.Value;

            app.dEditField5.Visible = 'off';
            app.dEditField5Label.Visible = 'off';
            app.meanEditField5.Visible = 'off';
            app.meanEditField5Label.Visible = 'off';
            app.varianceEditField5.Visible = 'off';
            app.varianceEditField5Label.Visible = 'off';

            switch value
                case 'Salt and Pepper Noise'
                    app.dEditField5.Visible = 'on';
                    app.dEditField5Label.Visible = 'on';

                    app.dEditField5.Value = 0.075;
                case 'Gaussian Noise'
                    app.meanEditField5.Visible = 'on';
                    app.meanEditField5Label.Visible = 'on';
                    app.varianceEditField5.Visible = 'on';
                    app.varianceEditField5Label.Visible = 'on';

                    app.meanEditField5.Value = -0.2;
                    app.varianceEditField5.Value = 0.1;
            end

            section5_apply_noise_and_filter(app);
        end

        % Value changed function: FilterDropDown5
        function FilterDropDown5ValueChanged(app, event)
            value = app.FilterDropDown5.Value;

            app.QEditField5.Visible = 'off';
            app.QEditField5Label.Visible = 'off';
            app.alphaEditField5.Visible = 'off';
            app.alphaEditField5Label.Visible = 'off';

            switch value
                case 'Min Filter'
                case 'Max Filter'
                case 'Median Filter'
                case 'Arithmetic Mean Filter'
                case 'Geometric Filter'
                case 'Harmonic Mean Filter'
                case 'Contraharmonic Mean Filter'
                    app.QEditField5.Visible = 'on';
                    app.QEditField5Label.Visible = 'on';
                    
                    app.QEditField5.Value = 1.5;
                case 'Midpoint Filter'
                case 'Alpha-Trimmed Mean Filter'
                    app.alphaEditField5.Visible = 'on';
                    app.alphaEditField5Label.Visible = 'on';
                    
                    app.alphaEditField5.Value = 0.1;
            end

            section5_apply_noise_and_filter(app);
        end

        % Value changed function: QEditField5, alphaEditField5, 
        % ...and 3 other components
        function Section5EditFieldValueChanged(app, event)
            section5_apply_noise_and_filter(app);
        end

        % Button pushed function: PilihGambarButton7
        function PilihGambarButton7Pushed(app, event)
            [app.sec7in, fullPath] = browseImage(app);
            app.srcEditField7.Value = fullPath;
            imshow(app.sec7in, 'Parent', app.UIAxes7_Input);
            section7_apply(app);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            section7_apply(app);
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            value = app.TypeDropDown.ValueIndex;
            switch value
                case 1
                    app.LengthEditField.Visible = true;
                    app.LengthEditFieldLabel.Visible = true;
                    app.ThetadegreeEditField.Visible = true;
                    app.ThetadegreeEditFieldLabel.Visible = true;
                    app.D0EditField7.Visible = false;
                    app.FilterSizeLabel.Visible = false;
                    app.SigmaEditField7.Visible = false;
                    app.SigmaLabel.Visible = false;
                case 2
                    app.LengthEditField.Visible = false;
                    app.LengthEditFieldLabel.Visible = false;
                    app.ThetadegreeEditField.Visible = false;
                    app.ThetadegreeEditFieldLabel.Visible = false;
                    app.D0EditField7.Visible = true;
                    app.FilterSizeLabel.Visible = true;
                    app.SigmaEditField7.Visible = true;
                    app.SigmaLabel.Visible = true;
            end
        end

        % Value changed function: GambarDropDown
        function GambarDropDownValueChanged(app, event)
            section6_update(app, true);
        end

        % Button pushed function: PilihGambarButton4
        function PilihGambarButton4Pushed(app, event)
            [img, fullPath] = browseImage(app);
            app.srcEditField4.Value = fullPath;
            imshow(img, 'Parent', app.UIAxes4_Input);
            section4_apply_filter(app);
        end

        % Value changed function: D0EditField4, alphaEditField4
        function Section4EditFieldValueChanged(app, event)
            section4_apply_filter(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Soal1Tab = uitab(app.TabGroup);
            app.Soal1Tab.Title = 'Soal 1';
            app.UIAxes_Matlab = uiaxes(app.Soal1Tab);
            title(app.UIAxes_Matlab, 'Matlab')
            app.UIAxes_Output = uiaxes(app.Soal1Tab);
            title(app.UIAxes_Output, 'Output')
            app.UIAxes_Input = uiaxes(app.Soal1Tab);
            title(app.UIAxes_Input, 'Input')
            app.srcEditFieldLabel = uilabel(app.Soal1Tab);
            app.srcEditFieldLabel.Text = 'src';
            app.MaskinvalidLabel = uilabel(app.Soal1Tab);
            app.MaskinvalidLabel.Visible = 'off';
            app.MaskinvalidLabel.Text = 'Mask invalid';
            app.InputMaskTextArea = uitextarea(app.Soal1Tab);
            app.InputMaskTextArea.ValueChangedFcn = createCallbackFcn(app, @InputMaskTextAreaValueChanged, true);
            app.InputMaskTextArea.Value = {'0 -1 0'; '-1 4 -1'; '0 -1 0'};
            app.PilihGambarButton = uibutton(app.Soal1Tab, 'push');
            app.PilihGambarButton.ButtonPushedFcn = createCallbackFcn(app, @PilihGambarButtonPushed, true);
            app.PilihGambarButton.Text = 'Pilih Gambar';
            app.srcEditField = uieditfield(app.Soal1Tab, 'text');
            app.InputMaskTextAreaLabel = uilabel(app.Soal1Tab);
            app.InputMaskTextAreaLabel.Text = 'Input Mask';
            app.Soal2Tab = uitab(app.TabGroup);
            app.Soal2Tab.Title = 'Soal 2';
            app.UIAxes2_Output = uiaxes(app.Soal2Tab);
            title(app.UIAxes2_Output, 'Output')
            app.UIAxes2_Input = uiaxes(app.Soal2Tab);
            title(app.UIAxes2_Input, 'Input')
            app.srcEditField2_Label = uilabel(app.Soal2Tab);
            app.srcEditField2_Label.Text = 'src';
            app.OperationDropDown2Label = uilabel(app.Soal2Tab);
            app.OperationDropDown2Label.Text = 'Operation';
            app.D0EditField2Label = uilabel(app.Soal2Tab);
            app.D0EditField2Label.Visible = 'off';
            app.D0EditField2Label.Text = 'D0';
            app.sigmaEditField2Label = uilabel(app.Soal2Tab);
            app.sigmaEditField2Label.Visible = 'off';
            app.sigmaEditField2Label.Text = 'sigma';
            app.nEditField2_2 = uieditfield(app.Soal2Tab, 'numeric');
            app.nEditField2_2.RoundFractionalValues = 'on';
            app.nEditField2_2.ValueDisplayFormat = '%g';
            app.nEditField2_2.AllowEmpty = 'on';
            app.nEditField2_2.ValueChangedFcn = createCallbackFcn(app, @Section2EditFieldValueChanged, true);
            app.nEditField2_2.Visible = 'off';
            app.nEditField2_2.Value = 1;
            app.D0EditField2 = uieditfield(app.Soal2Tab, 'numeric');
            app.D0EditField2.AllowEmpty = 'on';
            app.D0EditField2.ValueChangedFcn = createCallbackFcn(app, @Section2EditFieldValueChanged, true);
            app.D0EditField2.Visible = 'off';
            app.D0EditField2.Value = 50;
            app.sigmaEditField2 = uieditfield(app.Soal2Tab, 'numeric');
            app.sigmaEditField2.RoundFractionalValues = 'on';
            app.sigmaEditField2.ValueDisplayFormat = '%g';
            app.sigmaEditField2.AllowEmpty = 'on';
            app.sigmaEditField2.ValueChangedFcn = createCallbackFcn(app, @Section2EditFieldValueChanged, true);
            app.sigmaEditField2.Visible = 'off';
            app.sigmaEditField2.Value = 3;
            app.nEditField2 = uieditfield(app.Soal2Tab, 'numeric');
            app.nEditField2.RoundFractionalValues = 'on';
            app.nEditField2.ValueDisplayFormat = '%g';
            app.nEditField2.AllowEmpty = 'on';
            app.nEditField2.ValueChangedFcn = createCallbackFcn(app, @Section2EditFieldValueChanged, true);
            app.nEditField2.Value = 3;
            app.OperationDropDown2 = uidropdown(app.Soal2Tab);
            app.OperationDropDown2.Items = {'Mean Filter', 'Gaussian Filter', 'ILPF', 'GLPF', 'BLPF'};
            app.OperationDropDown2.ValueChangedFcn = createCallbackFcn(app, @OperationDropDown2ValueChanged, true);
            app.OperationDropDown2.Value = 'Mean Filter';
            app.PilihGambarButton2 = uibutton(app.Soal2Tab, 'push');
            app.PilihGambarButton2.ButtonPushedFcn = createCallbackFcn(app, @PilihGambarButton2Pushed, true);
            app.PilihGambarButton2.Text = 'Pilih Gambar';
            app.srcEditField2 = uieditfield(app.Soal2Tab, 'text');
            app.nEditField2Label = uilabel(app.Soal2Tab);
            app.nEditField2Label.Text = 'n';
            app.nEditField2_2Label = uilabel(app.Soal2Tab);
            app.nEditField2_2Label.Visible = 'off';
            app.nEditField2_2Label.Text = 'n';
            app.Soal3Tab = uitab(app.TabGroup);
            app.Soal3Tab.Title = 'Soal 3';
            app.UIAxes3_Output = uiaxes(app.Soal3Tab);
            title(app.UIAxes3_Output, 'Output')
            app.UIAxes3_Input = uiaxes(app.Soal3Tab);
            title(app.UIAxes3_Input, 'Input')
            app.srcEditField3Label = uilabel(app.Soal3Tab);
            app.srcEditField3Label.Text = 'src';
            app.OperationDropDown3Label = uilabel(app.Soal3Tab);
            app.OperationDropDown3Label.Text = 'Operation';
            app.nEditField3Label = uilabel(app.Soal3Tab);
            app.nEditField3Label.Visible = 'off';
            app.nEditField3Label.Text = 'n';
            app.nEditField3 = uieditfield(app.Soal3Tab, 'numeric');
            app.nEditField3.RoundFractionalValues = 'on';
            app.nEditField3.ValueDisplayFormat = '%g';
            app.nEditField3.AllowEmpty = 'on';
            app.nEditField3.Visible = 'off';
            app.nEditField3.Value = 1;
            app.D0EditField3Label = uilabel(app.Soal3Tab);
            app.D0EditField3Label.Text = 'D0';
            app.D0EditField3 = uieditfield(app.Soal3Tab, 'numeric');
            app.D0EditField3.AllowEmpty = 'on';
            app.D0EditField3.ValueChangedFcn = createCallbackFcn(app, @Section3EditFieldValueChanged, true);
            app.D0EditField3.Value = 50;
            app.OperationDropDown3 = uidropdown(app.Soal3Tab);
            app.OperationDropDown3.Items = {'IHPF', 'GHPF', 'BHPF'};
            app.OperationDropDown3.ValueChangedFcn = createCallbackFcn(app, @OperationDropDown3ValueChanged, true);
            app.OperationDropDown3.Value = 'IHPF';
            app.PilihGambarButton3 = uibutton(app.Soal3Tab, 'push');
            app.PilihGambarButton3.ButtonPushedFcn = createCallbackFcn(app, @PilihGambarButton3Pushed, true);
            app.PilihGambarButton3.Text = 'Pilih Gambar';
            app.srcEditField3 = uieditfield(app.Soal3Tab, 'text');
            app.Soal4Tab = uitab(app.TabGroup);
            app.Soal4Tab.Title = 'Soal 4';
            app.UIAxes4_Output = uiaxes(app.Soal4Tab);
            title(app.UIAxes4_Output, 'Output')
            app.UIAxes4_Input = uiaxes(app.Soal4Tab);
            title(app.UIAxes4_Input, 'Input')
            app.srcEditField3Label_2 = uilabel(app.Soal4Tab);
            app.srcEditField3Label_2.Text = 'src';
            app.D0EditField3Label_2 = uilabel(app.Soal4Tab);
            app.D0EditField3Label_2.Text = 'D0';
            app.nEditField3Label_2 = uilabel(app.Soal4Tab);
            app.nEditField3Label_2.Text = 'alpha';
            app.alphaEditField4 = uieditfield(app.Soal4Tab, 'numeric');
            app.alphaEditField4.AllowEmpty = 'on';
            app.alphaEditField4.ValueChangedFcn = createCallbackFcn(app, @Section4EditFieldValueChanged, true);
            app.alphaEditField4.Value = 1.5;
            app.D0EditField4 = uieditfield(app.Soal4Tab, 'numeric');
            app.D0EditField4.AllowEmpty = 'on';
            app.D0EditField4.ValueChangedFcn = createCallbackFcn(app, @Section4EditFieldValueChanged, true);
            app.D0EditField4.Value = 30;
            app.PilihGambarButton4 = uibutton(app.Soal4Tab, 'push');
            app.PilihGambarButton4.ButtonPushedFcn = createCallbackFcn(app, @PilihGambarButton4Pushed, true);
            app.PilihGambarButton4.Text = 'Pilih Gambar';
            app.srcEditField4 = uieditfield(app.Soal4Tab, 'text');
            app.Soal5Tab = uitab(app.TabGroup);
            app.Soal5Tab.Title = 'Soal 5';
            app.UIAxes5_Filtered = uiaxes(app.Soal5Tab);
            title(app.UIAxes5_Filtered, 'Filtered')
            app.UIAxes5_Noisy = uiaxes(app.Soal5Tab);
            title(app.UIAxes5_Noisy, 'Noisy')
            app.UIAxes5_Input = uiaxes(app.Soal5Tab);
            title(app.UIAxes5_Input, 'Input')
            app.srcEditField_2Label = uilabel(app.Soal5Tab);
            app.srcEditField_2Label.Text = 'src';
            app.OperationDropDown3Label_2 = uilabel(app.Soal5Tab);
            app.OperationDropDown3Label_2.Text = 'Noise';
            app.OperationDropDown3Label_3 = uilabel(app.Soal5Tab);
            app.OperationDropDown3Label_3.Text = 'Filter';
            app.alphaEditField5Label = uilabel(app.Soal5Tab);
            app.alphaEditField5Label.Visible = 'off';
            app.alphaEditField5Label.Text = 'alpha';
            app.alphaEditField5 = uieditfield(app.Soal5Tab, 'numeric');
            app.alphaEditField5.AllowEmpty = 'on';
            app.alphaEditField5.ValueChangedFcn = createCallbackFcn(app, @Section5EditFieldValueChanged, true);
            app.alphaEditField5.Visible = 'off';
            app.alphaEditField5.Value = 0.1;
            app.QEditField5Label = uilabel(app.Soal5Tab);
            app.QEditField5Label.Visible = 'off';
            app.QEditField5Label.Text = 'Q';
            app.QEditField5 = uieditfield(app.Soal5Tab, 'numeric');
            app.QEditField5.AllowEmpty = 'on';
            app.QEditField5.ValueChangedFcn = createCallbackFcn(app, @Section5EditFieldValueChanged, true);
            app.QEditField5.Visible = 'off';
            app.QEditField5.Value = 1.5;
            app.nEditField5_2 = uieditfield(app.Soal5Tab, 'numeric');
            app.nEditField5_2.RoundFractionalValues = 'on';
            app.nEditField5_2.ValueDisplayFormat = '%g';
            app.nEditField5_2.AllowEmpty = 'on';
            app.nEditField5_2.Value = 3;
            app.FilterDropDown5 = uidropdown(app.Soal5Tab);
            app.FilterDropDown5.Items = {'Min Filter', 'Max Filter', 'Median Filter', 'Arithmetic Mean Filter', 'Geometric Filter', 'Harmonic Mean Filter', 'Contraharmonic Mean Filter', 'Midpoint Filter', 'Alpha-Trimmed Mean Filter'};
            app.FilterDropDown5.ValueChangedFcn = createCallbackFcn(app, @FilterDropDown5ValueChanged, true);
            app.FilterDropDown5.Value = 'Min Filter';
            app.varianceEditField5Label = uilabel(app.Soal5Tab);
            app.varianceEditField5Label.Visible = 'off';
            app.varianceEditField5Label.Text = 'variance';
            app.varianceEditField5 = uieditfield(app.Soal5Tab, 'numeric');
            app.varianceEditField5.AllowEmpty = 'on';
            app.varianceEditField5.ValueChangedFcn = createCallbackFcn(app, @Section5EditFieldValueChanged, true);
            app.varianceEditField5.Visible = 'off';
            app.varianceEditField5.Value = 0.1;
            app.meanEditField5Label = uilabel(app.Soal5Tab);
            app.meanEditField5Label.Visible = 'off';
            app.meanEditField5Label.Text = 'mean';
            app.meanEditField5 = uieditfield(app.Soal5Tab, 'numeric');
            app.meanEditField5.AllowEmpty = 'on';
            app.meanEditField5.ValueChangedFcn = createCallbackFcn(app, @Section5EditFieldValueChanged, true);
            app.meanEditField5.Visible = 'off';
            app.meanEditField5.Value = -0.2;
            app.dEditField5Label = uilabel(app.Soal5Tab);
            app.dEditField5Label.Text = 'd';
            app.dEditField5 = uieditfield(app.Soal5Tab, 'numeric');
            app.dEditField5.AllowEmpty = 'on';
            app.dEditField5.ValueChangedFcn = createCallbackFcn(app, @Section5EditFieldValueChanged, true);
            app.dEditField5.Value = 0.075;
            app.NoiseDropDown5 = uidropdown(app.Soal5Tab);
            app.NoiseDropDown5.Items = {'Salt and Pepper Noise', 'Gaussian Noise'};
            app.NoiseDropDown5.ValueChangedFcn = createCallbackFcn(app, @NoiseDropDown5ValueChanged, true);
            app.NoiseDropDown5.Value = 'Salt and Pepper Noise';
            app.PilihGambarButton5 = uibutton(app.Soal5Tab, 'push');
            app.PilihGambarButton5.ButtonPushedFcn = createCallbackFcn(app, @PilihGambarButton5Pushed, true);
            app.PilihGambarButton5.Text = 'Pilih Gambar';
            app.srcEditField5 = uieditfield(app.Soal5Tab, 'text');
            app.nEditField2Label_2 = uilabel(app.Soal5Tab);
            app.nEditField2Label_2.Text = 'n';
            app.Soal6Tab = uitab(app.TabGroup);
            app.Soal6Tab.Title = 'Soal 6';
            app.UIAxes6_Input = uiaxes(app.Soal6Tab);
            title(app.UIAxes6_Input, 'Input')
            app.UIAxes6_Input.XTick = [];
            app.UIAxes6_Input.YTick = [];
            app.UIAxes6_Output = uiaxes(app.Soal6Tab);
            title(app.UIAxes6_Output, 'Output')
            xlabel(app.UIAxes6_Output, 'X')
            ylabel(app.UIAxes6_Output, 'Y')
            zlabel(app.UIAxes6_Output, 'Z')
            app.GambarDropDownLabel = uilabel(app.Soal6Tab);
            app.GambarDropDownLabel.Text = 'Gambar';
            app.GambarDropDown = uidropdown(app.Soal6Tab);
            app.GambarDropDown.Items = {'a', 'b', 'c', 'd', 'e', 'f'};
            app.GambarDropDown.ValueChangedFcn = createCallbackFcn(app, @GambarDropDownValueChanged, true);
            app.GambarDropDown.Value = 'a';
            app.Soal7Tab = uitab(app.TabGroup);
            app.Soal7Tab.Title = 'Soal 7';
            app.UIAxes7_Output = uiaxes(app.Soal7Tab);
            title(app.UIAxes7_Output, 'Output')
            app.UIAxes7_Input = uiaxes(app.Soal7Tab);
            title(app.UIAxes7_Input, 'Input')
            app.UIAxes7_Input.XTick = [];
            app.UIAxes7_Input.YTick = [];
            app.PilihGambarButton7 = uibutton(app.Soal7Tab, 'push');
            app.PilihGambarButton7.ButtonPushedFcn = createCallbackFcn(app, @PilihGambarButton7Pushed, true);
            app.PilihGambarButton7.Text = 'Pilih Gambar';
            app.srcEditField_2Label_2 = uilabel(app.Soal7Tab);
            app.srcEditField_2Label_2.Text = 'src';
            app.srcEditField7 = uieditfield(app.Soal7Tab, 'text');
            app.OperationDropDown3Label_4 = uilabel(app.Soal7Tab);
            app.OperationDropDown3Label_4.Text = 'Tipe';
            app.TypeDropDown = uidropdown(app.Soal7Tab);
            app.TypeDropDown.Items = {'in -> motion -> deconv', 'in -> deconv mean'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = 'in -> motion -> deconv';
            app.LengthEditFieldLabel = uilabel(app.Soal7Tab);
            app.LengthEditFieldLabel.Text = 'Length';
            app.LengthEditField = uieditfield(app.Soal7Tab, 'numeric');
            app.LengthEditField.Value = 20;
            app.ThetadegreeEditFieldLabel = uilabel(app.Soal7Tab);
            app.ThetadegreeEditFieldLabel.Text = 'Theta (degree)';
            app.ThetadegreeEditField = uieditfield(app.Soal7Tab, 'numeric');
            app.ThetadegreeEditField.Value = 45;
            app.FilterSizeLabel = uilabel(app.Soal7Tab);
            app.FilterSizeLabel.Visible = 'off';
            app.FilterSizeLabel.Text = 'D0';
            app.D0EditField7 = uieditfield(app.Soal7Tab, 'numeric');
            app.D0EditField7.Visible = 'off';
            app.D0EditField7.Value = 15;
            app.ApplyButton = uibutton(app.Soal7Tab, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.KEditFieldLabel = uilabel(app.Soal7Tab);
            app.KEditFieldLabel.Text = 'K';
            app.KEditField = uieditfield(app.Soal7Tab, 'numeric');
            app.KEditField.Value = 1e-05;
            app.PanelUiAxes7 = uipanel(app.Soal7Tab);
            app.UIAxes7_Blur = uiaxes(app.PanelUiAxes7);
            title(app.UIAxes7_Blur, 'Blur')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end